classdef REACT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        T2STARMAPCheckBox          matlab.ui.control.CheckBox
        DWICheckBox                matlab.ui.control.CheckBox
        MOLLICheckBox              matlab.ui.control.CheckBox
        VFACheckBox                matlab.ui.control.CheckBox
        DCECheckBox                matlab.ui.control.CheckBox
        SelectimagingmodalitiesforregistrationLabel  matlab.ui.control.Label
        LoaddataforVisit1Button    matlab.ui.control.Button
        OET2STARMAPCheckBox        matlab.ui.control.CheckBox
        OEMOLLICheckBox            matlab.ui.control.CheckBox
        OEVFACheckBox              matlab.ui.control.CheckBox
        AirstandardLabel           matlab.ui.control.Label
        OxygenEnhancedOELabel      matlab.ui.control.Label
        Visit1EditFieldLabel       matlab.ui.control.Label
        Visit1EditField            matlab.ui.control.EditField
        Visit2EditFieldLabel       matlab.ui.control.Label
        Visit2EditField            matlab.ui.control.EditField
        Enterdirectoriesforpatientvisits1and2Label  matlab.ui.control.Label
        LoaddataforVisit2Button    matlab.ui.control.Button
        RegisterButton_v1          matlab.ui.control.Button
        RegisterButton_v2          matlab.ui.control.Button
        VisitnumberLabel           matlab.ui.control.Label
        VisitNumberDropDown        matlab.ui.control.DropDown
        RegisteredROILabel         matlab.ui.control.Label
        RegisteredRoiDropDown      matlab.ui.control.DropDown
        LoadcorrespondingquantitativemapsLabel  matlab.ui.control.Label
        LoadmapsforVisit1Button    matlab.ui.control.Button
        LoadmapsforVisit2Button    matlab.ui.control.Button
        AnalyseROIvaluesButton     matlab.ui.control.Button
        ImagePanel1                matlab.ui.container.Panel
        ImagePanel2                matlab.ui.container.Panel
        Label_1                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        WelcometothrLabel          matlab.ui.control.Label
        DisplayimagesandROIsLabel  matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        OverviewTab                matlab.ui.container.Tab
        StatsPanel1a               matlab.ui.container.Panel
        StatsPanel1b               matlab.ui.container.Panel
        HistogramsTab              matlab.ui.container.Tab
        StatsPanel2a               matlab.ui.container.Panel
        StatsPanel2b               matlab.ui.container.Panel
        RegisteredROIOELabel       matlab.ui.control.Label
        RegisteredRoiDropDown_OE   matlab.ui.control.DropDown
        ExportEditField            matlab.ui.control.EditField
        Enterfilepathendingincsvtosavedataforvisits1and2Label  matlab.ui.control.Label
        ExportButton               matlab.ui.control.Button
        UpdateButton               matlab.ui.control.Button
        UpdateButton_2             matlab.ui.control.Button
        ExportEditField_2          matlab.ui.control.EditField
        ExportButton_2             matlab.ui.control.Button
        Visit2EditFieldLabel_2     matlab.ui.control.Label
        Visit2EditFieldLabel_3     matlab.ui.control.Label
    end

    
    % this is essentially required to store global variables in the app 
    
    properties (Access = private)
        Raw_Options = {'T2* STARMAP', 'DWI', 'MOLLI', 'VFA', 'DCE'};
        Map_Options = {'T2* map', 'ADC map', 'T1 map (MOLLI)', 'T1 map (VFA)', 'k-trans map'};
        Checked = zeros(1, 5);
        OE_Raw_Options = {'OE T2* STARMAP', '', 'OE MOLLI', 'OE VFA', ''};
        OE_Map_Options = {'OE T2* map', '', 'OE T1 map (MOLLI)', 'OE T1 map (VFA)', ''};
        OE_Checked = zeros(1,5);
        DATA_PATH_1 = '/Users/ambrebertrand/Documents/Oxford_Engineering/4th_year/4YP/Data/ART_128_MRI250717/';
        DATA_PATH_2 = '/Users/ambrebertrand/Documents/Oxford_Engineering/4th_year/4YP/Data/ART_128_MRI180817/';    
        EXPORT_PATH = '/Users/ambrebertrand/Downloads/outputs.csv';
        OE_EXPORT_PATH = '/Users/ambrebertrand/Downloads/OE_outputs.csv';
        Reg_Dimensions = 2;
        Nb_Of_Modalities
        Modality_Idx
        OE_Nb_Of_Modalities
        OE_Modality_Idx
        % 
        Fixed_Files_1       % file names
        Moving_File_1
        Roi_File_1
        Roi_Reg_1           % created in register button
        Quanti_Files_1
        %
        Fixed_Files_2
        Moving_File_2
        Roi_File_2
        Roi_Reg_2
        Quanti_Files_2      
        %
        OE_Fixed_Files_1
        OE_Roi_Reg_1           % created in register button
        OE_Quanti_Files_1
        %
        OE_Fixed_Files_2
        OE_Roi_Reg_2
        OE_Quanti_Files_2
        % 
        Tumour_Volume
        % image arrays for display (output of register fctn)       
        All_Reg_Outputs_1 
        All_Reg_Outputs_2 
        OE_All_Reg_Outputs_1
        OE_All_Reg_Outputs_2
        VisitNumber
        % outputs used for the histo plots and images
        Modality_To_Display = cell(1,5);   % 5 is number of variables this takes but this is NOT related to nb of modalities
        OE_Modality_To_Display = cell(1,5);
        Histo_Vals            
        OE_Histo_Vals    
        % outputs used for the export button to save csv files
        Csv_Outputs
        OE_Csv_Outputs

    end
    
    
    % functions required to run the code 
    
    methods (Access = private)
        
        function [reg_roi,reg_outputs] = register(app, fixed_file, moving_file, roi_file, reg_dimensions, modality_tag, visit_nb)
             
            % ----- PREPROCESSING -----   
        
            % 4th dimension timepoint for fixed image (index 1 for T2* STARMAP, 2 for DWI, 3 for MOLLI etc)
            % 3rd dimension slice selection for fixed image
            timepoint_4d = [0, 1, 9/11, 0, 2/65];
            timepoint_3d = [0, 8, 0, 8, 14];  
            
            % slice number for the T2WI 
            % **** TAKE CARE, DIFFERENT SLICE FOR EACH VISIT ****
            if visit_nb == 1
                slice_moving = 5;    % correct slice for visit 1
            elseif visit_nb==2
                slice_moving = 10;
            end
            
            %% Process data to obtain correct 2D or 3D format
            
            % "fixed" is the selected raw data reference map
            fixed = niftiread(fixed_file);
            fixed = fliplr(rot90(fixed,3));
            fixed_info = niftiinfo(fixed_file);
            % here, select correct timepoint if data is 4d
            if length(size(fixed)) == 4
                t = round( timepoint_4d(modality_tag) * size(fixed,4) );
                fixed = fixed(:,:,:,t);    % last index is timepoint
            end
            % add padding if necessary
            if size(fixed, 3)~= 1 && size(fixed, 3) < 16
                padding = round((16 - size(fixed,3))/2);
                padded_fixed = uint16(zeros(256, 256, size(fixed,3)+2*padding));
                padded_fixed(:, :, padding+1:size(padded_fixed,3)-padding) = fixed;
                fixed = padded_fixed;
            end
            
            % "moving" is the selected input image (T2 weighted)
            moving = niftiread(moving_file);
            moving = fliplr(rot90(moving,3));
            moving_info = niftiinfo(moving_file);
            % add padding if necessary
            if size(moving, 3)~= 1 && size(moving, 3) < 16
                padding = round((16 - size(moving,3))/2);
                padded_moving = uint16(zeros(256, 256, size(moving,3)+2*padding));
                padded_moving(:, :, padding+1:size(padded_moving,3)-padding) = moving;
                moving = padded_moving;
            end
            
            % "roi" is the region of interest in the input image (T2-weighted)
            roi = niftiread(roi_file);
            roi = fliplr(rot90(roi,3));
            roi_info = niftiinfo(roi_file);
            % add padding if necessary
            if size(roi, 3)~= 1 && size(roi, 3) < 16
                padding = round((16 - size(roi,3))/2);
                padded_roi = uint16(zeros(256, 256, size(roi,3)+2*padding));
                padded_roi(:, :, padding+1:size(padded_roi,3)-padding) = roi;
                roi = padded_roi;
            end
            
            % get spatial references using the get_relative_imrefs.m script
            [R_fixed, R_moving] = get_relative_imrefs(fixed_info, moving_info, reg_dimensions);
            [~, R_roi] = get_relative_imrefs(fixed_info, roi_info, reg_dimensions);
            
            % extract individual slices for 2D registration
            %  /!\ manual for now but could be automated /!\
            if reg_dimensions == 2
                if length(size(fixed)) == 3
                    slice_fixed = timepoint_3d(modality_tag);
                    fixed = fixed(:,:,slice_fixed);
                end               
                moving = moving(:,:,slice_moving);
                roi = roi(:,:,slice_moving);
            end
            
            % ----- REGISTRATION ------
        
            %% Performs a 2D-to-2D image registration of a T2 image onto a raw data (T1 molli) reference map
            % Or 3D to 3D if both images are 3D
            
            % Select optimiser, metric and registration type
            [optimizer,metric] = imregconfig('multimodal');
            
            % Parameters to be tuned for specific application
            optimizer.InitialRadius = 0.003;         % 0.01
            optimizer.Epsilon = 1.5e-4;               % 5e-6
            optimizer.GrowthFactor = 1.01;         % 1.01
            optimizer.MaximumIterations = 5000;         % 5000
            
            % Compute transform matrix
            %tform = imregtform(moving_filtered, R_moving, fixed_filtered, R_fixed, 'affine', optimizer, metric);
            tform = imregtform(moving, R_moving, fixed, R_fixed, 'affine', optimizer, metric);
            
            % ROI is transformed using transformation matrix "tform" computed above
            [reg_moving, R_reg_moving] = imwarp(moving, R_moving, tform, 'OutputView', R_fixed);
            [reg_roi, R_reg_roi] = imwarp(roi,R_moving,tform,'OutputView',R_fixed);
                                    
            reg_outputs = {fixed, R_fixed, moving, R_moving, reg_moving, R_reg_moving, roi, R_roi, reg_roi, R_reg_roi, visit_nb};
    
            % end of registration          
        end 

        function [R1, R2] = get_relative_imrefs(app, info1, info2, reg_type)
            %% Get relative spatial references for the two images to be registered
            
            % Ambre's edits from the original script:
            % modified to allow for spatial references of 4D timeseries data to be extracted
            % reg_type to perform 2D to 2D reg on 3D data if slice extraction is required
            
            info = [info1, info2];
            dimensions = [length(info1.ImageSize), length(info2.ImageSize)];
            
            % reduce dimensionality
            for i=1:2
                if dimensions(i) == 4
                    dimensions(i) = 3;   % in case the image is a 4D timeseries
                end
                if reg_type == 2
                    dimensions(i) = 2;
                end
            end
            
            % Initialise Q that will store the coordinates for four corners of the
            % image
            Q = zeros(3, 4*length(info));
            
            % Assuming images have the same dimensionality, either info1 or info2 can
            % be used in next line
            if dimensions(1) == 3
                Q = [Q; zeros(1, 4*length(info))];
            end            
            
            %% For 3D registration there must be a minimum of 16 slices
            for i = 1:length(info)
                padding = 0;
                if dimensions(1) == 3
                    z_pixels = info(i).ImageSize(3);
                    if info(i).ImageSize(3) < 16
                        padding = round((16 - info(i).ImageSize(3))/2);
                        info(i).ImageSize(3) = info(i).ImageSize(3) + 2*padding;
                    end
                end
                
                %% Calculate world coordinates by applying the transformations stored in the header
                x_pixels = info(i).ImageSize(1);
                y_pixels = info(i).ImageSize(2);
                
                P = [-0.5, -0.5, -0.5-padding 1;                       %top left corner of bottom layer
                    x_pixels-0.5, -0.5, -0.5-padding 1;               %top right corner of bottom layer
                    -0.5, y_pixels-0.5, -0.5-padding 1];               %bottom left corner of bottom layer
                
                if dimensions(1) == 3
                    P = [P; -0.5, -0.5, z_pixels-0.5+padding 1];              %top left corner of top layer
                end
                
                %Location of corners in world coordinates
                Q(:,1+(i-1)*4:i*4) = P * info(i).Transform.T;
            end
            
            %Remove the columns that were only needed to apply affine transformation
            Q = Q(:, [1:3, 5:7]);
            
            %unit vectors in the original x y and z directions after the transformation
            n_x = (Q(2, 1:3)-Q(1, 1:3))/norm(Q(2, 1:3)-Q(1, 1:3));
            n_y = (Q(3, 1:3)-Q(1, 1:3))/norm(Q(3, 1:3)-Q(1, 1:3));
            
            %Determine the Image Extent In World Coordinates
            x1 = norm(Q(2, 1:3)-Q(1, 1:3));
            y1 = norm(Q(3, 1:3)-Q(1, 1:3));
            x2 = norm(Q(2, 4:6)-Q(1, 4:6));
            y2 = norm(Q(3, 4:6)-Q(1, 4:6));
            
            %Determine the relative distance between the upper left corners of the
            %two resulting images
            dx = dot(n_x, Q(1, 4:6)-Q(1, 1:3));
            dy = dot(n_y, Q(1, 4:6)-Q(1, 1:3));
            
            if dimensions(2) == 3
                n_z = (Q(4, 1:3)-Q(1, 1:3))/norm(Q(4, 1:3)-Q(1, 1:3));
                z1 = norm(Q(4, 1:3)-Q(1, 1:3));
                z2 = norm(Q(4, 4:6)-Q(1, 4:6));
                dz = dot(n_z, Q(1, 4:6)-Q(1, 1:3));
                R1 = imref3d(info(1).ImageSize(1:3), [0 x1], [0 y1], [0 z1]);
                R2 = imref3d(info(2).ImageSize(1:3), [dx x2+dx], [dy y2+dy], [dz z2+dz]);
            else
                R1 = imref2d(info(1).ImageSize(1:3), [0 x1], [0 y1]);
                R2 = imref2d(info(2).ImageSize(1:3), [dx x2+dx], [dy y2+dy]);               
            end

        end  
           
        function [output,histo_vals,values_for_csv] = histograms(app, roi_vals_vis1, roi_vals_vis2, modality_name, modality_tag)
            
            %% script to extract voxel ROI data from Mirada output
            % Daniel Bulte, IBME, University of Oxford, December 2020
            
            % edited by Ambre Bertrand, February 2021
            % use this version as part of the registration pipeline
            
            %% set vector to plot fits
            rango = transpose(linspace(0,2000,200));
            
            if modality_tag == 1 % for t2* because the range of values is much smaller
                rango = transpose(linspace(0,60,200));            
            end
            
            %% ROI for visit 1
            
            roivals1 = double(roi_vals_vis1(:));
            roivals1 = roivals1(roivals1~=0);
            roivals1(le(roivals1,0))=nan;
            numvox1 = length(roivals1(:));
            
            % find mean and stddev of all values
            mean1 = mean(roivals1,'omitnan');
            std1 = std(roivals1,'omitnan');
            skew1 = skewness(roivals1);
            kurt1 = kurtosis(roivals1);
            
            % fit 1, 2 & 3 component GMM's to data and select best one
            
            AIC_1 = zeros(1,3);
            GMModels_1 = cell(1,3);
            options = statset('MaxIter',500);
            
            for k = 1:3
                GMModels_1{k} = fitgmdist(roivals1,k,'Options',options,'Regularization',0.2);
                AIC_1(k)= GMModels_1{k}.AIC;
            end
            
            [minAIC_1,numComponents_1] = min(AIC_1);
            
            GMModel_1 = GMModels_1{numComponents_1};
            
            ExpCovariance_1(1:numComponents_1) = GMModel_1.Sigma(:,:,1:numComponents_1);
            ExpSigma_1 = sqrt(ExpCovariance_1);
            
            % create multi-component GMM fit to plot over histogram
            gm1 = gmdistribution(GMModel_1.mu,GMModel_1.Sigma,GMModel_1.ComponentProportion);
            gmPDF1 = pdf(gm1,rango);

            %% repeat above for second visit
            
            roivals2 = double(roi_vals_vis2(:));
            roivals2 = roivals2(roivals2~=0);
            roivals2(le(roivals2,0))=nan;
            numvox2 = length(roivals2(:));
            
            mean2 = mean(roivals2,'omitnan');
            std2 = std(roivals2,'omitnan');
            skew2 = skewness(roivals2);
            kurt2 = kurtosis(roivals2);
            
            % fit 1, 2 & 3 component GMM's to data and select best one
            AIC_2 = zeros(1,3);
            GMModels_2 = cell(1,3);
            options = statset('MaxIter',500);
            
            for k = 1:3
                GMModels_2{k} = fitgmdist(roivals2,k,'Options',options,'Regularization',0.2);
                AIC_2(k)= GMModels_2{k}.AIC;
            end
            
            [minAIC_2,numComponents_2] = min(AIC_2);
            
            GMModel_2 = GMModels_2{numComponents_2};
            
            ExpCovariance_2(1:numComponents_2) = GMModel_2.Sigma(:,:,1:numComponents_2);
            ExpSigma_2 = sqrt(ExpCovariance_2);      
                 
            gm2 = gmdistribution(GMModel_2.mu,GMModel_2.Sigma,GMModel_2.ComponentProportion);
            gmPDF2 = pdf(gm2,rango);
            
            
            % ----- save values for future plots -----
            histo_vals = {rango,roivals1,numvox1,gmPDF1,roivals2,numvox2,gmPDF2};
            
            
            %% Determine degree of response by reduction of low ADC peaks
            
            outprop1 = zeros(1,3);
            outmu1 = zeros(1,3);
            outsig1 = zeros(1,3);
            
            lowvol_1 = 0;
            for comp = 1:numComponents_1
                if GMModel_1.mu(comp)<1200 % value was chosen arbitrarily and needs to be determined properly
                    lowvol_1 = lowvol_1 + numvox1*GMModel_1.ComponentProportion(comp);
                end
                outprop1(comp) = GMModel_1.ComponentProportion(comp);
                outmu1(comp) = transpose(GMModel_1.mu(comp));
                outsig1(comp) = ExpSigma_1(comp);
            end
            
            outprop2 = zeros(1,3);
            outmu2 = zeros(1,3);
            outsig2 = zeros(1,3);
            
            lowvol_2 = 0;
            for comp = 1:numComponents_2
                if GMModel_2.mu(comp)<1200 % value was chosen arbitrarily and needs to be determined properly
                    lowvol_2 = lowvol_2 + numvox2*GMModel_2.ComponentProportion(comp);
                end
                outprop2(comp) = GMModel_2.ComponentProportion(comp);
                outmu2(comp) = transpose(GMModel_2.mu(comp));
                outsig2(comp) = ExpSigma_2(comp);
            end
            
            deltalowpeak = 100*(lowvol_2-lowvol_1)./lowvol_1;
            
            deltamean = 100*(mean2-mean1)./mean1;
            
            deltavol = 100*(numvox2-numvox1)./numvox1;
            
            output = [numvox1 mean1 std1 outprop1 outmu1 outsig1 numvox2 mean2 std2 outprop2 outmu2 outsig2 deltavol deltamean deltalowpeak];
            
            % this is for the csv outputs
            % 'Visit','Tumour volume (cm^3)','Biomarker','Mean','SD','Number of GMM components','Component 1 mean','Component 2 mean','Component 3 mean','Component 1 prop','Component 2 prop','Component 3 prop'
            values_for_csv = [1,0,0,mean1,std1,numComponents_1,outmu1(1),outmu1(2),outmu1(3),outprop1(1),outprop1(2),outprop1(3);
                2,0,0,mean2,std2,numComponents_2,outmu2(1),outmu2(2),outmu2(3),outprop2(1),outprop2(2),outprop2(3)];
            % keep 2nd and 3rd index empty for tumour volume and modality name
            
            
        end

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Visit1EditField
        function Visit1EditFieldValueChanged(app, event)
            value = app.Visit1EditField.Value;
            app.DATA_PATH_1 = value;
        end

        % Value changed function: Visit2EditField
        function Visit2EditFieldValueChanged(app, event)
            value = app.Visit2EditField.Value;
            app.DATA_PATH_2 = value;
        end

        % Value changed function: T2STARMAPCheckBox
        function T2STARMAPCheckBoxValueChanged(app, event)
            
            value = app.T2STARMAPCheckBox.Value;
            
            switch value
                case 0
                    app.OET2STARMAPCheckBox.Enable = 'off';
                    app.Checked(1) = 0;
                case 1
                    app.OET2STARMAPCheckBox.Enable = 'on';
                    app.Checked(1) = 1;
            end
                        
        end

        % Value changed function: MOLLICheckBox
        function MOLLICheckBoxValueChanged(app, event)
            
            value = app.MOLLICheckBox.Value;
            
            switch value
                case 0
                    app.OEMOLLICheckBox.Enable = 'off';
                    app.Checked(3) = 0;
                case 1
                    app.OEMOLLICheckBox.Enable = 'on';
                    app.Checked(3) = 3;
            end
            
        end

        % Value changed function: VFACheckBox
        function VFACheckBoxValueChanged(app, event)
            
            value = app.VFACheckBox.Value;
            
            switch value
                case 0
                    app.OEVFACheckBox.Enable = 'off';
                    app.Checked(4) = 0;
                case 1
                    app.OEVFACheckBox.Enable = 'on';
                    app.Checked(4) = 4;
            end
            
        end

        % Value changed function: DWICheckBox
        function DWICheckBoxValueChanged(app, event)
            value = app.DWICheckBox.Value;
            
            switch value
                case 0
                    app.Checked(2) = 0;
                case 1
                    app.Checked(2) = 2;
            end
            
        end

        % Value changed function: DCECheckBox
        function DCECheckBoxValueChanged(app, event)
            value = app.DCECheckBox.Value;
            
            switch value
                case 0
                    app.Checked(5) = 0;
                case 1
                    app.Checked(5) = 5;
            end
        end

        % Value changed function: OET2STARMAPCheckBox
        function OET2STARMAPCheckBoxValueChanged(app, event)
            value = app.OET2STARMAPCheckBox.Value;
            
            switch value
                case 0
                    app.OE_Checked(1) = 0;
                case 1
                    app.OE_Checked(1) = 1;
            end
            
        end

        % Value changed function: OEMOLLICheckBox
        function OEMOLLICheckBoxValueChanged(app, event)
            value = app.OEMOLLICheckBox.Value;
            
            switch value
                case 0
                    app.OE_Checked(3) = 0;
                case 1
                    app.OE_Checked(3) = 3;
            end
        end

        % Value changed function: OEVFACheckBox
        function OEVFACheckBoxValueChanged(app, event)
            value = app.OEVFACheckBox.Value;
            
            switch value
                case 0
                    app.OE_Checked(4) = 0;
                case 1
                    app.OE_Checked(4) = 4;
            end
        end

        % Button pushed function: LoaddataforVisit1Button
        function LoaddataforVisit1ButtonPushed(app, event)
            P1 = genpath(app.DATA_PATH_1);
            addpath(P1)
            cd(app.DATA_PATH_1)
            
            v = 1;  % visit number
                          
            % select T2 weighted image and ROI for Visit 1
            f = msgbox(sprintf('Select T2-weighted image (moving) for Visit %d', v)); uiwait(f);
            app.Moving_File_1 = uigetfile('*.nii.gz','Select moving image');
            figure(app.UIFigure);
            f = msgbox(sprintf('Select ROI for Visit %d', v)); uiwait(f);
            app.Roi_File_1 = uigetfile('*.nii.gz','Select ROI');  
            figure(app.UIFigure);
    
%             app.Moving_File_1 = '/Users/ambrebertrand/Documents/Oxford_Engineering/4th_year/4YP/Data/ART_128_MRI250717/6_ax_frfse_t2_hr.nii.gz';
%             app.Roi_File_1 = '/Users/ambrebertrand/Documents/Oxford_Engineering/4th_year/4YP/Data/ART_128_MRI250717/0_RTSTRUCT_MRI_1_1-label.nii.gz';
%     
                
            % create vector of indices for checked modalities
            app.Checked = app.Checked(app.Checked~=0);      % eg for molli and vfa checked this will be [3,4]
            app.Nb_Of_Modalities = length(app.Checked);
                        
            % create lists to store filename of each fixed modality and ROI
            app.Fixed_Files_1 = cell(1,app.Nb_Of_Modalities);
            app.Roi_Reg_1 = cell(1,app.Nb_Of_Modalities);
            
            % load all selected fixed modalities to register
            for m=1:app.Nb_Of_Modalities               
                modality_name = cell2mat(app.Raw_Options(app.Checked(m)));
                f = msgbox(sprintf('Select %s (fixed) for Visit %d', modality_name, v)); uiwait(f);
                app.Fixed_Files_1{m} = uigetfile('*.nii.gz');
                figure(app.UIFigure);
            end
            
            
            % --- repeat process for OE if selected ---
            app.OE_Checked = app.OE_Checked(app.OE_Checked~=0);      % eg for molli and vfa checked this will be [3,4]
            app.OE_Nb_Of_Modalities = length(app.OE_Checked);
            
            if app.OE_Nb_Of_Modalities ~= 0 
                
                signal = 0;  % reset to wait until OE files are loaded
                
                % create lists to store filename of each fixed modality and ROI
                app.OE_Fixed_Files_1 = cell(1,app.OE_Nb_Of_Modalities);
                app.OE_Roi_Reg_1 = cell(1,app.OE_Nb_Of_Modalities);
                
                % load all selected fixed modalities to register
                for m=1:app.OE_Nb_Of_Modalities
                    modality_name = cell2mat(app.OE_Raw_Options(app.OE_Checked(m)));
                    f = msgbox(sprintf('Select %s (fixed) for Visit %d', modality_name, v)); uiwait(f);
                    app.OE_Fixed_Files_1{m} = uigetfile('*.nii.gz');
                    figure(app.UIFigure);
                    % once all the required data has been loaded
                    if m==app.OE_Nb_Of_Modalities
                        signal = 1; 
                    end
                end
                
            else 
                signal = 1;
            end
            
            
            % show registration button
            if signal == 1
                app.RegisterButton_v1.Enable = 'on';               
            end
                  
             
        end

        % Button pushed function: LoaddataforVisit2Button
        function LoaddataforVisit2ButtonPushed(app, event)
            P2 = genpath(app.DATA_PATH_2);
            addpath(P2)
            cd(app.DATA_PATH_2)
            
            v = 2;  % visit number
                          
            % select T2 weighted image and ROI for Visit 2
            f = msgbox(sprintf('Select T2-weighted image (moving) for Visit %d', v)); uiwait(f);
            app.Moving_File_2 = uigetfile('*.nii.gz','Select moving image');
            figure(app.UIFigure);
            f = msgbox(sprintf('Select ROI for Visit %d', v)); uiwait(f);
            app.Roi_File_2 = uigetfile('*.nii.gz','Select ROI');  
            figure(app.UIFigure);

%             app.Moving_File_2 = '/Users/ambrebertrand/Documents/Oxford_Engineering/4th_year/4YP/Data/ART_128_MRI180817/4_ax_frfse_t2_hr.nii.gz';
%             app.Roi_File_2 = '/Users/ambrebertrand/Documents/Oxford_Engineering/4th_year/4YP/Data/ART_128_MRI180817/0 RTSTRUCT MRI_1_2-label.nii.gz';

            
            % we already have Checked and Nb_Of_Modalities
            
            % create lists to store filename of each fixed modality and ROI
            app.Fixed_Files_2 = cell(1,app.Nb_Of_Modalities);
            app.Roi_Reg_2 = cell(1,app.Nb_Of_Modalities);
            
            % load all selected fixed modalities to register
            for m=1:app.Nb_Of_Modalities
                modality_name = cell2mat(app.Raw_Options(app.Checked(m)));
                f = msgbox(sprintf('Select %s (fixed) for Visit %d', modality_name, v)); uiwait(f);
                app.Fixed_Files_2{m} = uigetfile('*.nii.gz');
                figure(app.UIFigure);
            end
            
            
            % --- repeat process for OE if selected ---
           
            % we already have OE_Checked and OE_Nb_Of_Modalities
            
            if app.OE_Nb_Of_Modalities ~= 0
                
                signal = 0;  % reset to wait until OE files are loaded
                
                % create lists to store filename of each fixed modality and ROI
                app.OE_Fixed_Files_2 = cell(1,app.OE_Nb_Of_Modalities);
                app.OE_Roi_Reg_2 = cell(1,app.OE_Nb_Of_Modalities);
                
                % load all selected fixed modalities to register
                for m=1:app.OE_Nb_Of_Modalities
                    modality_name = cell2mat(app.OE_Raw_Options(app.OE_Checked(m)));
                    f = msgbox(sprintf('Select %s (fixed) for Visit %d', modality_name, v)); uiwait(f);
                    app.OE_Fixed_Files_2{m} = uigetfile('*.nii.gz');
                    figure(app.UIFigure);
                    % once all the required data has been loaded
                    if m==app.OE_Nb_Of_Modalities
                        signal = 1; 
                    end
                end
            else 
                signal = 1;
            end
            
            
            % show registration button
            if signal == 1
                app.RegisterButton_v2.Enable = 'on';
            end
            
        end

        % Button pushed function: RegisterButton_v1
        function RegisterButton_v1Pushed(app, event)
            
            app.Label_1.Text = 'Please wait...';
            drawnow
                               
            % visit number
            v = 1;    
                       
            % assign correct variables
            fixed_file = app.Fixed_Files_1;
            moving_file = app.Moving_File_1;
            roi_file = app.Roi_File_1;
            
            app.All_Reg_Outputs_1 = cell(1,app.Nb_Of_Modalities);
            
            for m=1:app.Nb_Of_Modalities 
                
                % compute registered ROI binary mask
                % app.Checked(m) is the modality index (1-5)
                [reg_roi,reg_outputs] = register(app, fixed_file{m}, moving_file, roi_file, app.Reg_Dimensions, app.Checked(m), v);
                reg_roi = fliplr(rot90(reg_roi,2));
                % assign computed ROI to correct modality
                app.Roi_Reg_1{m} = reg_roi;
                % global store image arrays and references for that modality
                app.All_Reg_Outputs_1{app.Checked(m)} = reg_outputs;  % rawmod_name,fixed,moving,roi,reg_roi
                
                % create options for the dropdown menu
                new_option = cell2mat(app.Raw_Options(app.Checked(m)));
                app.RegisteredRoiDropDown.Items{1+m} = sprintf('%s', new_option);
                app.RegisteredRoiDropDown.ItemsData{1+m} = m;
            
            end
            
            % ----- repeat process for OE modalities -----
            
            if app.OE_Nb_Of_Modalities ~= 0
                                
                % assign correct variables
                fixed_file = app.OE_Fixed_Files_1;
                moving_file = app.Moving_File_1;
                roi_file = app.Roi_File_1;
                
                app.OE_All_Reg_Outputs_1 = cell(1,app.OE_Nb_Of_Modalities);

                
                for m=1:app.OE_Nb_Of_Modalities
                    
                    % compute registered ROI binary mask
                    [reg_roi,reg_outputs] = register(app, fixed_file{m}, moving_file, roi_file, app.Reg_Dimensions, app.OE_Checked(m), v);
                    reg_roi = fliplr(rot90(reg_roi,2));
                    % assign computed ROI to correct modality
                    app.OE_Roi_Reg_1{m} = reg_roi;
                    % global store image arrays and references for that modality
                    app.OE_All_Reg_Outputs_1{app.OE_Checked(m)} = reg_outputs;
                   
                    % create options for the dropdown menu
                    new_option = cell2mat(app.OE_Raw_Options(app.OE_Checked(m)));
                    app.RegisteredRoiDropDown_OE.Items{1+m} = sprintf('%s', new_option);
                    app.RegisteredRoiDropDown_OE.ItemsData{1+m} = m;
                    
                    if m==app.OE_Nb_Of_Modalities
                        app.Label_1.Text = 'Complete.';
                    end
                end
            
            else
                app.Label_1.Text = 'Complete.';
            end
                     
        end

        % Button pushed function: RegisterButton_v2
        function RegisterButton_v2Pushed(app, event)
            
            app.Label_2.Text = 'Please wait...';
            drawnow
                               
            % visit number
            v = 2;    
        
            % assign correct variables
            fixed_file = app.Fixed_Files_2;
            moving_file = app.Moving_File_2;
            roi_file = app.Roi_File_2;
               
            
            app.All_Reg_Outputs_2 = cell(1,app.Nb_Of_Modalities);

            
            for m=1:app.Nb_Of_Modalities 
                
                % compute registered ROI binary mask
                [reg_roi,reg_outputs] = register(app, fixed_file{m}, moving_file, roi_file, app.Reg_Dimensions, app.Checked(m), v);
                reg_roi = fliplr(rot90(reg_roi,2));
                % assign computed ROI to correct modality
                app.Roi_Reg_2{m} = reg_roi;
                % global store image arrays and references for that modality
                app.All_Reg_Outputs_2{app.Checked(m)} = reg_outputs;
                
            end
            
            % ----- repeat process for OE modalities -----
            
            if app.OE_Nb_Of_Modalities ~= 0
                                
                % assign correct variables
                fixed_file = app.OE_Fixed_Files_2;
                moving_file = app.Moving_File_2;
                roi_file = app.Roi_File_2;
                
                app.OE_All_Reg_Outputs_2 = cell(1,app.OE_Nb_Of_Modalities);

                
                for m=1:app.OE_Nb_Of_Modalities
                    
                    % compute registered ROI binary mask
                    [reg_roi,reg_outputs] = register(app, fixed_file{m}, moving_file, roi_file, app.Reg_Dimensions, app.OE_Checked(m), v);
                    reg_roi = fliplr(rot90(reg_roi,2));
                    % assign computed ROI to correct modality
                    app.OE_Roi_Reg_2{m} = reg_roi;
                    % global store image arrays and references for that modality
                    app.OE_All_Reg_Outputs_2{app.OE_Checked(m)} = reg_outputs;
  
                    if m==app.OE_Nb_Of_Modalities
                        app.Label_2.Text = 'Complete.';
                    end
                end
            
            else 
                app.Label_2.Text = 'Complete.';
            end
            
        end

        % Button pushed function: LoadmapsforVisit1Button
        function LoadmapsforVisit1ButtonPushed(app, event)
            P1 = genpath(app.DATA_PATH_1);
            addpath(P1)
            cd(app.DATA_PATH_1)
            
            v = 1;  % visit number

            % create lists to store filename of each quantitative map
            app.Quanti_Files_1 = cell(1,app.Nb_Of_Modalities);
            
            % load all quantitative maps corresponding to modalities selected
            for m=1:app.Nb_Of_Modalities
                modality_name = cell2mat(app.Map_Options(app.Checked(m)));
                f = msgbox(sprintf('Select %s for Visit %d', modality_name, v)); uiwait(f);
                app.Quanti_Files_1{m} = uigetfile('*.nii.gz');
                figure(app.UIFigure);
               
            end
            
            % ----- repeat process for OE if selected -----
            
            if app.OE_Nb_Of_Modalities ~= 0
          
                % create lists to store filename of each quantitative map
                app.OE_Quanti_Files_1 = cell(1,app.OE_Nb_Of_Modalities);
                
                % load all quantitative maps corresponding to modalities selected
                for m=1:app.OE_Nb_Of_Modalities
                    modality_name = cell2mat(app.OE_Map_Options(app.OE_Checked(m)));
                    f = msgbox(sprintf('Select %s for Visit %d', modality_name, v)); uiwait(f);
                    app.OE_Quanti_Files_1{m} = uigetfile('*.nii.gz');
                    figure(app.UIFigure);
                    
                end
            
            end
            
            % -------------------------------------

            
        end

        % Button pushed function: LoadmapsforVisit2Button
        function LoadmapsforVisit2ButtonPushed(app, event)
            
            P2 = genpath(app.DATA_PATH_2);
            addpath(P2)
            cd(app.DATA_PATH_2)
            
            v = 2;  % visit number
            
            % create lists to store filename of each quantitative map
            app.Quanti_Files_2 = cell(1,app.Nb_Of_Modalities);
  
            % load all quantitative maps corresponding to modalities selected
            for m=1:app.Nb_Of_Modalities
                modality_name = cell2mat(app.Map_Options(app.Checked(m)));
                f = msgbox(sprintf('Select %s for Visit %d', modality_name, v)); uiwait(f);
                app.Quanti_Files_2{m} = uigetfile('*.nii.gz');
                figure(app.UIFigure);
            end
            
            
            % ----- repeat process for OE if selected -----
            
            if app.OE_Nb_Of_Modalities ~= 0

                % create lists to store filename of each quantitative map
                app.OE_Quanti_Files_2 = cell(1,app.OE_Nb_Of_Modalities);
                
                % load all quantitative maps corresponding to modalities selected
                for m=1:app.OE_Nb_Of_Modalities
                    modality_name = cell2mat(app.OE_Map_Options(app.OE_Checked(m)));
                    f = msgbox(sprintf('Select %s for Visit %d', modality_name, v)); uiwait(f);
                    app.OE_Quanti_Files_2{m} = uigetfile('*.nii.gz');
                    figure(app.UIFigure);

                    if m==app.OE_Nb_Of_Modalities
                        signal = 1; % once all the required data has been loaded
                    end
                end
                
            else
                signal = 1;                           
            end
            
            % -------------------------------------
            
  
            if signal == 1
                app.AnalyseROIvaluesButton.Enable = 'on';
            end
            
            
        end

        % Button pushed function: AnalyseROIvaluesButton
        function AnalyseROIvaluesButtonPushed(app, event)
            
            % initialise variables
            roi_vals_1 = cell(1, app.Nb_Of_Modalities);
            roi_vals_2 = cell(1, app.Nb_Of_Modalities);
            modality_name = cell(1, app.Nb_Of_Modalities);
            modality_idx = app.Checked;         % remember this is array with selection indices eg [3,4] for molli,vfa
            reg_dimensions = app.Reg_Dimensions;
            
            for v=1:2

                for m=1:app.Nb_Of_Modalities
                    
                    modality_name{m} = cell2mat(app.Map_Options(modality_idx(m)));
                    
                    if v==1
                        quantimap_file = app.Quanti_Files_1{m};
                    elseif v==2
                        quantimap_file = app.Quanti_Files_2{m};
                    end
                    
                    % --------- same preprocessing as in register.m ----------------
                    quanti = niftiread(quantimap_file);
                    % rotate the non-T1 maps correctly
                    if (modality_idx(m) == 1) || (modality_idx(m) == 2) || (modality_idx(m) == 5)
                        quanti = rot90(quanti);
                    else
                        quanti = fliplr(rot90(quanti,3));
                    end
                    % select correct timepoint if data is 4d
                    timepoints = [round(13/16), 1, round(6/11)];
                    if length(size(quanti)) == 4
                        if modality_idx(m)<=3     % only for starmap, dwi, molli
                            blob = timepoints(modality_idx(m)) * size(quanti,4);
                        end
                        quanti = quanti(:,:,:,blob);    % last index is timepoint
                    end
                    % add padding if necessary
                    if size(quanti, 3)~= 1 && size(quanti, 3) < 16
                        padding = round((16 - size(quanti,3))/2);
                        padded_quanti = uint16(zeros(256, 256, size(quanti,3)+2*padding));
                        padded_quanti(:, :, padding+1:size(padded_quanti,3)-padding) = quanti;
                        quanti = padded_quanti;
                    end
                    % extract individual slice for 2D case
                    slice_nb=10;     % same as for registration
                    if reg_dimensions == 2
                        if length(size(quanti)) == 3
                            quanti = quanti(:,:,slice_nb);
                        end
                    end
                    % ----------------------------------------------------
                    
                    % extract tumour values using ROI
                    if v==1
                        quantimap1 = int16(quanti);
                        roi_vals_1{m} = app.Roi_Reg_1{m} .* quantimap1;
                    elseif v==2
                        quantimap2 = int16(quanti);
                        roi_vals_2{m} = app.Roi_Reg_2{m} .* quantimap2;
                    end                    
                end
            end   
                
                % ----- same process for OE if selected -----              
    
            if app.OE_Nb_Of_Modalities ~= 0
            
                % initialise variables
                OE_roi_vals_1 = cell(1, app.OE_Nb_Of_Modalities);
                OE_roi_vals_2 = cell(1, app.OE_Nb_Of_Modalities);
                OE_modality_name = cell(1, app.OE_Nb_Of_Modalities);
                modality_idx = app.OE_Checked;
                
                for v=1:2
                
                    for m=1:app.OE_Nb_Of_Modalities
                        
                        OE_modality_name{m} = cell2mat(app.OE_Map_Options(modality_idx(m)));
                        
                        if v==1
                            quantimap_file = app.OE_Quanti_Files_1{m};
                        elseif v==2
                            quantimap_file = app.OE_Quanti_Files_2{m};
                        end
                        
                        % --------- same preprocessing as in register.m ----------------
                        quanti = niftiread(quantimap_file);
                        % rotate the non-T1 maps correctly
                        if (modality_idx(m) == 1) || (modality_idx(m) == 2) || (modality_idx(m) == 5)
                            quanti = rot90(quanti);
                        else
                            quanti = fliplr(rot90(quanti,3));
                        end
                        % select correct timepoint if data is 4d
                        % select correct timepoint if data is 4d
                        timepoints = [13/16, 1, 6/11, 0, 2/65];
                        if length(size(quanti)) == 4
                            if modality_idx(m)~=4     % for everyone but VFA
                                t = round(timepoints(modality_idx(m)) * size(quanti,4));
                            end
                            quanti = quanti(:,:,:,t);    % last index is timepoint
                        end
                        % add padding if necessary
                        if size(quanti, 3)~= 1 && size(quanti, 3) < 16
                            padding = round((16 - size(quanti,3))/2);
                            padded_quanti = uint16(zeros(256, 256, size(quanti,3)+2*padding));
                            padded_quanti(:, :, padding+1:size(padded_quanti,3)-padding) = quanti;
                            quanti = padded_quanti;
                        end
                        % extract individual slice for 2D case
                        slice_nb=10;     % same as for registration
                        if reg_dimensions == 2
                            if length(size(quanti)) == 3
                                quanti = quanti(:,:,slice_nb);
                            end
                        end
                        % ----------------------------------------------------
                        
                        % extract tumour values using ROI
                        if v==1
                            quantimap1 = int16(quanti);
                            OE_roi_vals_1{m} = app.OE_Roi_Reg_1{m} .* quantimap1;
                        elseif v==2
                            quantimap2 = int16(quanti);
                            OE_roi_vals_2{m} = app.OE_Roi_Reg_2{m} .* quantimap2;
                        end
                    end
                end   % end of visits 1 and 2
            
            end   % end of the OE part
            
            
            % create array to store volume for visits 1 and 2
            app.Tumour_Volume = zeros(1,2);
            
            % TUMOUR VOLUME
            for v=1:2
                if v==1
                    roi_file = app.Roi_File_1;
                else
                    roi_file = app.Roi_File_2;
                end
                roi_data = niftiread(roi_file);
                roi_info = niftiinfo(roi_file);
                dims = roi_info.PixelDimensions;   % gives 3 dims because voxels (struct is 3D)
                tumour_voxels = sum(roi_data(:));
                % compute volume in cm3
                app.Tumour_Volume(v) = tumour_voxels * dims(1) * dims(2) * dims(3) * 0.001;
            end
            
            
            % ----------- plots -----------
            
            % configure panel properties
            app.StatsPanel1a.AutoResizeChildren = 'off';
            app.StatsPanel1b.AutoResizeChildren = 'off';
                       
            % store means in a 1 by 5 vector for air visits 
            % (to be used in case of OE)
            Means_v1 = cell(1, length(app.Raw_Options));
            Means_v2 = cell(1, length(app.Raw_Options));
            Stds_v1 = cell(1, length(app.Raw_Options));
            Stds_v2 = cell(1, length(app.Raw_Options));
            
            % initialise hisogram outputs for later display 
            app.Histo_Vals = cell(1, app.Nb_Of_Modalities);
            % same for export csv button
            app.Csv_Outputs = cell(1, app.Nb_Of_Modalities);
            
            % plot bar charts with errors for air-only data
            for m=1:app.Nb_Of_Modalities
                
                % get output stats values from histograms
                modality_tag = app.Checked(m);
                [output,histo_vals,values_for_csv] = histograms(app, roi_vals_1{m}, roi_vals_2{m}, modality_name{m},modality_tag);
                app.Histo_Vals{m} = histo_vals;   % used in plots later
                app.Csv_Outputs{m} = values_for_csv;
                
                % this is a line of healthy vs diseased tissue (values tbc)
                thresholds = [27,1000,1000,1000,1000];
                
                % get individual values for visits 1 and 2 from ouput vector
                blob = num2cell(output(1:3));
                [numvox1, mean1, std1] = deal(blob{:});
                prop1 = output(4:6);
                mu1 = output(7:9);
                sigma1 = output(10:12);
                
                blob = num2cell(output(13:15));
                [numvox2, mean2, std2] = deal(blob{:});
                prop2 = output(16:18);
                mu2 = output(19:21);
                sigma2 = output(22:24);
                
                deltavol = output(25);
                deltamean = output(26);
                deltalowpeak = output(27);
                deltamu = 100*(mu2-mu1)./mu1;
                
                Means_v1{app.Checked(m)} = mean1;
                Means_v2{app.Checked(m)} = mean2;
                Stds_v1{app.Checked(m)} = std1;
                Stds_v2{app.Checked(m)} = std1;
                
                % bar chart with error bars
                axes = subplot(1, app.Nb_Of_Modalities+1, m, 'Parent', app.StatsPanel1a);                
                labels = {'Visit 1';'Visit 2'};
                x = 1:2;
                y = [mean1 mean2]';
                errors = [std1 std2];
                b = bar(axes,y,0.5);
                hold(axes,'on')
                er = errorbar(axes,x,y,errors,errors);
                er.Color = [0 0 0];
                er.LineStyle = 'none';
                hold(axes,'off')
                set(axes,'xticklabel',labels);
                title(axes,sprintf('Mean and std \n for %s', modality_name{m}));
                ylim(axes,[0 1.2*max([mean1,mean2])]);
                hold(axes,'on')
                yline(axes,thresholds(app.Checked(m)), '-r', 'LineWidth',3);                              
            end
            
            % add -- tumour volume -- to plots
            axes = subplot(1, app.Nb_Of_Modalities+1, app.Nb_Of_Modalities+1, 'Parent', app.StatsPanel1a);
            labels = {'Visit 1';'Visit 2'};
            y = [app.Tumour_Volume(1) app.Tumour_Volume(2)]';
            b = bar(axes,y,0.5);
            hold(axes,'on')
            set(axes,'xticklabel',labels);
            title(axes,sprintf('Tumour volume \n (x10^{-2}cm^3)'));
            ylim(axes,[0 1.2*max([app.Tumour_Volume(1),app.Tumour_Volume(1)])]);
            hold(axes,'on')
               
            
            % initialise hisogram outputs for later display 
            app.OE_Histo_Vals = cell(1, app.OE_Nb_Of_Modalities);
            % same for export csv button
            app.OE_Csv_Outputs = cell(1, app.OE_Nb_Of_Modalities);
            
            % same for OE, but plot differences between air-o2 instead of raw values            
            for m=1:app.OE_Nb_Of_Modalities
                
                % get output stats values from histograms
                OE_modality_tag = app.OE_Checked(m);
                [output,histo_vals,values_for_csv] = histograms(app, OE_roi_vals_1{m}, OE_roi_vals_2{m}, OE_modality_name{m},OE_modality_tag);
                app.OE_Histo_Vals{m} = histo_vals;   % used in plots later
                app.OE_Csv_Outputs{m} = values_for_csv;
                
                % get individual values for visits 1 and 2 from ouput vector
                blob = num2cell(output(1:3));
                [numvox1, mean1, std1] = deal(blob{:});
                prop1 = output(4:6);
                mu1 = output(7:9);
                sigma1 = output(10:12);
                
                blob = num2cell(output(13:15));
                [numvox2, mean2, std2] = deal(blob{:});
                prop2 = output(16:18);
                mu2 = output(19:21);
                sigma2 = output(22:24);
                
                deltavol = output(25);
                deltamean = output(26);
                deltalowpeak = output(27);
                deltamu = 100*(mu2-mu1)./mu1;
                
                % for OE, compute difference between means air-O2
                idx = app.OE_Checked(m);
                mean1 = Means_v1{idx} - mean1;
                mean2 = Means_v2{idx} - mean2;
                std1 = sqrt(Stds_v1{idx}^2 + std1^2);
                std2 = sqrt(Stds_v2{idx}^2 + std2^2);

                % bar chart with error bars
                axes = subplot(1, app.OE_Nb_Of_Modalities, m, 'Parent', app.StatsPanel1b);                
                labels = {'Visit 1';'Visit 2'};
                x = 1:2;
                y = [mean1 mean2]';
                errors = [std1 std2];
                b = bar(axes,y,0.5);
                hold(axes,'on')
                er = errorbar(axes,x,y,errors,errors);
                er.Color = [0 0 0];
                er.LineStyle = 'none';
                hold(axes,'off')
                set(axes,'xticklabel',labels);
                title(axes,sprintf('Air-Oxygen difference \n for %s', OE_modality_name{m}));
                %ylim(axes,[0 1.2*max([mean1,mean2])]);
                hold(axes,'on')
            end           
           
        end

        % Value changed function: VisitNumberDropDown
        function VisitNumberDropDownValueChanged(app, event)
            value = app.VisitNumberDropDown.Value;
            
            if strcmpi(value,'1') || value == 1
                app.VisitNumber = 1;
            elseif strcmpi(value,'2') || value == 2
                app.VisitNumber = 2;
            else
            end    
                  
        end

        % Value changed function: RegisteredRoiDropDown
        function RegisteredRoiDropDownValueChanged(app, event)
        
        % value gives the index of the items in the menu
            % equivalent to the items in Checked
            value = app.RegisteredRoiDropDown.Value;            
            rawmod_name = app.RegisteredRoiDropDown.Items{value+1}; %because first field is select/empty
            idx =app.RegisteredRoiDropDown.ItemsData{value+1};
            modality_idx = app.Checked(idx);
            
            % retrieve visit number from global variable
            visit_nb = app.VisitNumber;
            
            % extract correct image arrays from the regi outputs
            if visit_nb == 1
                fixed = app.All_Reg_Outputs_1{modality_idx}{1};
                moving = app.All_Reg_Outputs_1{modality_idx}{3};
                roi = app.All_Reg_Outputs_1{modality_idx}{7};
                reg_roi = app.All_Reg_Outputs_1{modality_idx}{9};
            else
                fixed = app.All_Reg_Outputs_2{modality_idx}{1};
                moving = app.All_Reg_Outputs_2{modality_idx}{3};
                roi = app.All_Reg_Outputs_2{modality_idx}{7};
                reg_roi = app.All_Reg_Outputs_2{modality_idx}{9};
            end
            
            % rotate to get the correct view when looking at the raw images and ROIs
            fixed = fliplr(rot90(fixed,2));
            moving = fliplr(rot90(moving,2));
            roi = fliplr(rot90(roi,2));
            reg_roi = fliplr(rot90(reg_roi,2));
            
            app.Modality_To_Display = {rawmod_name,fixed,moving,roi,reg_roi};

            
        end

        % Value changed function: RegisteredRoiDropDown_OE
        function RegisteredRoiDropDown_OEValueChanged(app, event)
            
            % value gives the index of the items in the menu
            % equivalent to the items in Checked
            value = app.RegisteredRoiDropDown_OE.Value;           
            rawmod_name = app.RegisteredRoiDropDown_OE.Items{value+1};
            idx = app.RegisteredRoiDropDown_OE.ItemsData{value+1};
            modality_idx = app.OE_Checked(idx);
            
            % retrieve visit number from global variable
            visit_nb = app.VisitNumber;
            
            % extract correct image arrays from the regi outputs
            if visit_nb == 1
                fixed = app.OE_All_Reg_Outputs_1{modality_idx}{1};
                moving = app.OE_All_Reg_Outputs_1{modality_idx}{3};
                roi = app.OE_All_Reg_Outputs_1{modality_idx}{7};
                reg_roi = app.OE_All_Reg_Outputs_1{modality_idx}{9};
            else
                fixed = app.OE_All_Reg_Outputs_2{modality_idx}{1};
                moving = app.OE_All_Reg_Outputs_2{modality_idx}{3};
                roi = app.OE_All_Reg_Outputs_2{modality_idx}{7};
                reg_roi = app.OE_All_Reg_Outputs_2{modality_idx}{9};
            end
            
            % rotate to get the correct view when looking at the raw images and ROIs
            fixed = fliplr(rot90(fixed,2));
            moving = fliplr(rot90(moving,2));
            roi = fliplr(rot90(roi,2));
            reg_roi = fliplr(rot90(reg_roi,2));
            
            app.OE_Modality_To_Display = {rawmod_name,fixed,moving,roi,reg_roi};
                   
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)

            modalities = app.Modality_To_Display;

            %rawmod_name = string(modalities(1));
            rawmod_name=modalities{1};
            fixed = modalities{2};
            moving = modalities{3};
            roi = modalities{4};
            reg_roi = modalities{5};
                      
           
              % ----------- plots -----------
              
            % configure panel properties
            cla(app.StatsPanel2a)    % clear
            app.StatsPanel2a.AutoResizeChildren = 'off';
                         
             % histograms plots
            idx = app.RegisteredRoiDropDown.Value;
            histo_vals_for_this_mod = app.Histo_Vals{idx};
            [rango,roivals1,numvox1,gmPDF1,roivals2,numvox2,gmPDF2] = deal(histo_vals_for_this_mod{:});

            
            %axes1 = axes('Parent',app.StatsPanel2a);
            axes1 = subplot(1,1,1,'Parent',app.StatsPanel2a);
            hold(axes1,'on')
            h1=histogram(axes1,roivals1,50);
            hold(axes1,'on')
            plot(rango,h1.BinWidth*numvox1*gmPDF1,'Parent',axes1,'LineWidth',2.0);
            hold(axes1,'on')
            h2=histogram(axes1,roivals2,50);
            hold(axes1,'on')
            title(axes1,sprintf('ROI values for %s, Visits 1 and 2', rawmod_name));
            plot(rango,h2.BinWidth*numvox2*gmPDF2,'Parent',axes1,'LineWidth',2.0);
            
            
            % configure panel properties
            app.ImagePanel1.AutoResizeChildren = 'off';
            app.ImagePanel2.AutoResizeChildren = 'off';
           
             
          % show original ROI on T2 weighted image
            h1 = subplot(1,1,1,'Parent',app.ImagePanel1);
            i=imagesc(moving,'Parent',h1);
            colormap(h1,gray)
            set(h1,'ydir','reverse');
            hold(h1,'on')
            axis(h1, 'equal')
            axis(h1, 'off')
            h1.Title.String = sprintf('ROI on original T2-weighted image Visit %d',app.VisitNumber);
            h2 = axes(app.ImagePanel1);
            imagesc('CData', moving.*roi, 'AlphaData', roi, 'AlphaDataMapping', 'scaled','Parent',h2);
            set(h2,'color','none','visible','off')
            colormap(h2,parula)
            set(h2,'ydir','reverse');
            c=colorbar(h2);
            c.Location = 'east';
            c.AxisLocation = 'out';
            linkaxes([h1 h2])
            set(h1, 'xlim', [0 size(moving, 2)], 'ylim', [0 size(moving, 1)])
            axis 'equal'
            
            % show registered ROI on functional modality
            h1 = subplot(1,1,1,'Parent',app.ImagePanel2);
            i=imagesc(fixed,'Parent',h1);
            colormap(h1,gray)
            set(h1,'ydir','reverse');
            hold(h1,'on')
            axis(h1, 'equal')
            axis(h1, 'off')
            h1.Title.String = sprintf('Registered ROI on fixed %s Visit %d', rawmod_name, app.VisitNumber);
            h2 = axes(app.ImagePanel2);
            imagesc('CData', int16(fixed).*int16(reg_roi), 'AlphaData', reg_roi, 'AlphaDataMapping', 'scaled','Parent',h2);
            set(h2,'color','none','visible','off')
            colormap(h2, parula)
            set(h2,'ydir','reverse');
            c=colorbar(h2);
            c.Location = 'east';
            c.AxisLocation = 'out';
            linkaxes([h1 h2])
            set(h1, 'xlim', [0 size(fixed, 2)], 'ylim', [0 size(fixed, 1)])
            axis equal


        end

        % Button pushed function: UpdateButton_2
        function UpdateButton_2Pushed(app, event)
            
            modalities = app.OE_Modality_To_Display;

            %rawmod_name = string(modalities(1));
            rawmod_name=modalities{1};
            fixed = modalities{2};
            moving = modalities{3};
            roi = modalities{4};
            reg_roi = modalities{5};
                      
           
              % ----------- plots -----------
              
            % configure panel properties
            cla(app.StatsPanel2b)    % clear
            app.StatsPanel2b.AutoResizeChildren = 'off';
                         
             % histograms plots
            idx = app.RegisteredRoiDropDown_OE.Value;
            histo_vals_for_this_mod = app.OE_Histo_Vals{idx};
            [rango,roivals1,numvox1,gmPDF1,roivals2,numvox2,gmPDF2] = deal(histo_vals_for_this_mod{:});

            axes1 = subplot(1,1,1,'Parent',app.StatsPanel2b);
            hold(axes1,'on')
            h1=histogram(axes1,roivals1,50);
            hold(axes1,'on')
            plot(rango,h1.BinWidth*numvox1*gmPDF1,'Parent',axes1,'LineWidth',2.0);
            hold(axes1,'on')
            h2=histogram(axes1,roivals2,50);
            hold(axes1,'on')
            title(axes1,sprintf('ROI values for %s, Visits 1 and 2', rawmod_name));
            plot(rango,h2.BinWidth*numvox2*gmPDF2,'Parent',axes1,'LineWidth',2.0);
            
            
            % configure panel properties
            app.ImagePanel1.AutoResizeChildren = 'off';
            app.ImagePanel2.AutoResizeChildren = 'off';
           
             
          % show original ROI on T2 weighted image
            h1 = subplot(1,1,1,'Parent',app.ImagePanel1);
            i=imagesc(moving,'Parent',h1);
            colormap(h1,gray)
            set(h1,'ydir','reverse');
            hold(h1,'on')
            axis(h1, 'equal')
            axis(h1, 'off')
            h1.Title.String = sprintf('ROI on original T2-weighted image Visit %d',app.VisitNumber);
            h2 = axes(app.ImagePanel1);
            imagesc('CData', moving.*roi, 'AlphaData', roi, 'AlphaDataMapping', 'scaled','Parent',h2);
            set(h2,'color','none','visible','off')
            colormap(h2,parula)
            set(h2,'ydir','reverse');
            c=colorbar(h2);
            c.Location = 'east';
            c.AxisLocation = 'out';
            linkaxes([h1 h2])
            set(h1, 'xlim', [0 size(moving, 2)], 'ylim', [0 size(moving, 1)])
            axis 'equal'
            
            % show registered ROI on functional modality
            h1 = subplot(1,1,1,'Parent',app.ImagePanel2);
            i=imagesc(fixed,'Parent',h1);
            colormap(h1,gray)
            set(h1,'ydir','reverse');
            hold(h1,'on')
            axis(h1, 'equal')
            axis(h1, 'off')
            h1.Title.String = sprintf('Registered ROI on fixed %s Visit %d', rawmod_name, app.VisitNumber);
            h2 = axes(app.ImagePanel2);
            imagesc('CData', int16(fixed).*int16(reg_roi), 'AlphaData', reg_roi, 'AlphaDataMapping', 'scaled','Parent',h2);
            set(h2,'color','none','visible','off')
            colormap(h2, parula)
            set(h2,'ydir','reverse');
            c=colorbar(h2);
            c.Location = 'east';
            c.AxisLocation = 'out';
            linkaxes([h1 h2])
            set(h1, 'xlim', [0 size(fixed, 2)], 'ylim', [0 size(fixed, 1)])
            axis equal
          
        
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %% Create csv with relevant stats
            
            csv_titles = [{'Visit'},'Tumour_volume_cm3','Biomarker','Mean','SD','Number_of_GMM_components','Comp_1_mean','Comp_2_mean','Comp_3_mean','Comp_1_prop','Comp_2_prop','Comp_3_prop'];
            csv_data = cell(app.Nb_Of_Modalities*2, length(csv_titles));
            
            for m=1:app.Nb_Of_Modalities
                
                % select correct modality at each iteration
                modality_idx = app.Checked(m);
                modality_name = cell2mat(app.Map_Options(modality_idx));
                
                % get data prepared from histograms script and fill in the gaps
                mod_data = num2cell(app.Csv_Outputs{m});
                mod_data{1,2} = app.Tumour_Volume(1);
                mod_data{1,3} = modality_name;
                mod_data{2,2} = app.Tumour_Volume(2);
                mod_data{2,3} = modality_name;
                
                % assign modality data to correct rows in the csv table
                csv_data((2*m-1):(2*m),:) = mod_data;
            end
            
            % create cell array with column titles and data
            csv_array = vertcat(csv_titles, csv_data);
            
            % convert cell to a table and use first row as variable names
            csv_table = cell2table(csv_array(2:end,:),'VariableNames',csv_array(1,:));
            
            % write the table to a CSV file
            output_filename = app.EXPORT_PATH;
            writetable(csv_table, output_filename)

        
        end

        % Value changed function: ExportEditField
        function ExportEditFieldValueChanged(app, event)
            value = app.ExportEditField.Value;
            app.EXPORT_PATH = value;
        end

        % Value changed function: ExportEditField_2
        function ExportEditField_2ValueChanged(app, event)
            value = app.ExportEditField_2.Value;
            app.OE_EXPORT_PATH = value;
        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)
              
        %% Create csv with relevant stats
            
            csv_titles = [{'Visit'},'Tumour_volume_cm3','Biomarker','Mean','SD','Number_of_GMM_components','Comp_1_mean','Comp_2_mean','Comp_3_mean','Comp_1_prop','Comp_2_prop','Comp_3_prop'];
            csv_data = cell(app.OE_Nb_Of_Modalities*2, length(csv_titles));
            
            for m=1:app.OE_Nb_Of_Modalities
                
                % select correct modality at each iteration
                modality_idx = app.OE_Checked(m);
                modality_name = cell2mat(app.OE_Map_Options(modality_idx));
                
                % get data prepared from histograms script and fill in the gaps
                mod_data = num2cell(app.OE_Csv_Outputs{m});
                mod_data{1,2} = app.Tumour_Volume(1);
                mod_data{1,3} = modality_name;
                mod_data{2,2} = app.Tumour_Volume(2);
                mod_data{2,3} = modality_name;
                
                % assign modality data to correct rows in the csv table
                csv_data((2*m-1):(2*m),:) = mod_data;
            end
            
            % create cell array with column titles and data
            csv_array = vertcat(csv_titles, csv_data);
            
            % convert cell to a table and use first row as variable names
            csv_table = cell2table(csv_array(2:end,:),'VariableNames',csv_array(1,:));
            
            % write the table to a CSV file
            output_filename = app.OE_EXPORT_PATH;
            writetable(csv_table, output_filename)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.T2STARMAPCheckBox = uicheckbox(app.UIFigure);
            app.T2STARMAPCheckBox.ValueChangedFcn = createCallbackFcn(app, @T2STARMAPCheckBoxValueChanged, true);
            app.T2STARMAPCheckBox.Text = 'T2* STARMAP';
            app.DWICheckBox = uicheckbox(app.UIFigure);
            app.DWICheckBox.ValueChangedFcn = createCallbackFcn(app, @DWICheckBoxValueChanged, true);
            app.DWICheckBox.Text = 'DWI';
            app.MOLLICheckBox = uicheckbox(app.UIFigure);
            app.MOLLICheckBox.ValueChangedFcn = createCallbackFcn(app, @MOLLICheckBoxValueChanged, true);
            app.MOLLICheckBox.Text = 'MOLLI';
            app.VFACheckBox = uicheckbox(app.UIFigure);
            app.VFACheckBox.ValueChangedFcn = createCallbackFcn(app, @VFACheckBoxValueChanged, true);
            app.VFACheckBox.Text = 'VFA';
            app.DCECheckBox = uicheckbox(app.UIFigure);
            app.DCECheckBox.ValueChangedFcn = createCallbackFcn(app, @DCECheckBoxValueChanged, true);
            app.DCECheckBox.Text = 'DCE';
            app.SelectimagingmodalitiesforregistrationLabel = uilabel(app.UIFigure);
            app.SelectimagingmodalitiesforregistrationLabel.Text = '2. Select imaging modalities for registration:';
            app.LoaddataforVisit1Button = uibutton(app.UIFigure, 'push');
            app.LoaddataforVisit1Button.ButtonPushedFcn = createCallbackFcn(app, @LoaddataforVisit1ButtonPushed, true);
            app.LoaddataforVisit1Button.Text = 'Load data for Visit 1';
            app.OET2STARMAPCheckBox = uicheckbox(app.UIFigure);
            app.OET2STARMAPCheckBox.ValueChangedFcn = createCallbackFcn(app, @OET2STARMAPCheckBoxValueChanged, true);
            app.OET2STARMAPCheckBox.Enable = 'off';
            app.OET2STARMAPCheckBox.Text = 'OE T2* STARMAP';
            app.OEMOLLICheckBox = uicheckbox(app.UIFigure);
            app.OEMOLLICheckBox.ValueChangedFcn = createCallbackFcn(app, @OEMOLLICheckBoxValueChanged, true);
            app.OEMOLLICheckBox.Enable = 'off';
            app.OEMOLLICheckBox.Text = 'OE MOLLI';
            app.OEVFACheckBox = uicheckbox(app.UIFigure);
            app.OEVFACheckBox.ValueChangedFcn = createCallbackFcn(app, @OEVFACheckBoxValueChanged, true);
            app.OEVFACheckBox.Enable = 'off';
            app.OEVFACheckBox.Text = 'OE VFA';
            app.AirstandardLabel = uilabel(app.UIFigure);
            app.AirstandardLabel.Text = 'Air (standard)';
            app.OxygenEnhancedOELabel = uilabel(app.UIFigure);
            app.OxygenEnhancedOELabel.Text = 'Oxygen-Enhanced (OE)';
            app.Visit1EditFieldLabel = uilabel(app.UIFigure);
            app.Visit1EditFieldLabel.Text = 'Visit 1';
            app.Visit1EditField = uieditfield(app.UIFigure, 'text');
            app.Visit1EditField.ValueChangedFcn = createCallbackFcn(app, @Visit1EditFieldValueChanged, true);
            app.Visit1EditField.Value = '/Users/ambrebertrand/Documents/Oxford_Engineering/4th_year/4YP/Data/ART_128_MRI250717/';
            app.Visit2EditFieldLabel = uilabel(app.UIFigure);
            app.Visit2EditFieldLabel.Text = 'Visit 2';
            app.Visit2EditField = uieditfield(app.UIFigure, 'text');
            app.Visit2EditField.ValueChangedFcn = createCallbackFcn(app, @Visit2EditFieldValueChanged, true);
            app.Visit2EditField.Value = '/Users/ambrebertrand/Documents/Oxford_Engineering/4th_year/4YP/Data/ART_128_MRI180817/';
            app.Enterdirectoriesforpatientvisits1and2Label = uilabel(app.UIFigure);
            app.Enterdirectoriesforpatientvisits1and2Label.Text = '1. Enter directories for patient visits 1 and 2:';
            app.LoaddataforVisit2Button = uibutton(app.UIFigure, 'push');
            app.LoaddataforVisit2Button.ButtonPushedFcn = createCallbackFcn(app, @LoaddataforVisit2ButtonPushed, true);
            app.LoaddataforVisit2Button.Text = 'Load data for Visit 2';
            app.RegisterButton_v1 = uibutton(app.UIFigure, 'push');
            app.RegisterButton_v1.ButtonPushedFcn = createCallbackFcn(app, @RegisterButton_v1Pushed, true);
            app.RegisterButton_v1.Enable = 'off';
            app.RegisterButton_v1.Text = 'Register';
            app.RegisterButton_v2 = uibutton(app.UIFigure, 'push');
            app.RegisterButton_v2.ButtonPushedFcn = createCallbackFcn(app, @RegisterButton_v2Pushed, true);
            app.RegisterButton_v2.Enable = 'off';
            app.RegisterButton_v2.Text = 'Register';
            app.VisitnumberLabel = uilabel(app.UIFigure);
            app.VisitnumberLabel.Text = 'Visit number';
            app.VisitNumberDropDown = uidropdown(app.UIFigure);
            app.VisitNumberDropDown.Items = {'1', '2'};
            app.VisitNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @VisitNumberDropDownValueChanged, true);
            app.VisitNumberDropDown.Value = '1';
            app.RegisteredROILabel = uilabel(app.UIFigure);
            app.RegisteredROILabel.Text = 'Registered ROI';
            app.RegisteredRoiDropDown = uidropdown(app.UIFigure);
            app.RegisteredRoiDropDown.Items = {'select...'};
            app.RegisteredRoiDropDown.ValueChangedFcn = createCallbackFcn(app, @RegisteredRoiDropDownValueChanged, true);
            app.RegisteredRoiDropDown.Value = 'select...';
            app.LoadcorrespondingquantitativemapsLabel = uilabel(app.UIFigure);
            app.LoadcorrespondingquantitativemapsLabel.Text = '3. Load corresponding quantitative maps: ';
            app.LoadmapsforVisit1Button = uibutton(app.UIFigure, 'push');
            app.LoadmapsforVisit1Button.ButtonPushedFcn = createCallbackFcn(app, @LoadmapsforVisit1ButtonPushed, true);
            app.LoadmapsforVisit1Button.Text = 'Load maps for Visit 1';
            app.LoadmapsforVisit2Button = uibutton(app.UIFigure, 'push');
            app.LoadmapsforVisit2Button.ButtonPushedFcn = createCallbackFcn(app, @LoadmapsforVisit2ButtonPushed, true);
            app.LoadmapsforVisit2Button.Text = 'Load maps for Visit 2';
            app.AnalyseROIvaluesButton = uibutton(app.UIFigure, 'push');
            app.AnalyseROIvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseROIvaluesButtonPushed, true);
            app.AnalyseROIvaluesButton.Enable = 'off';
            app.AnalyseROIvaluesButton.Text = 'Analyse ROI values';
            app.ImagePanel1 = uipanel(app.UIFigure);
            app.ImagePanel1.Title = 'Original ROI';
            app.ImagePanel2 = uipanel(app.UIFigure);
            app.ImagePanel2.Title = 'Registered ROI (modality-wise)';
            app.Label_1 = uilabel(app.UIFigure);
            app.Label_1.Text = {''; ''};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = ' ';
            app.WelcometothrLabel = uilabel(app.UIFigure);
            app.WelcometothrLabel.Text = 'Welcome to REACT-MRI (Registration, Extraction and Analysis of Cancer Tumour MRI Data)';
            app.DisplayimagesandROIsLabel = uilabel(app.UIFigure);
            app.DisplayimagesandROIsLabel.Text = '4. Display images and ROIs:';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OverviewTab = uitab(app.TabGroup);
            app.OverviewTab.Title = 'Overview';
            app.StatsPanel1a = uipanel(app.OverviewTab);
            app.StatsPanel1a.Title = 'Tumour statistics';
            app.StatsPanel1b = uipanel(app.OverviewTab);
            app.StatsPanel1b.Title = 'Tumour statistics (Oxygen-Enhanced)';
            app.HistogramsTab = uitab(app.TabGroup);
            app.HistogramsTab.Title = 'Histograms (modality-wise)';
            app.StatsPanel2a = uipanel(app.HistogramsTab);
            app.StatsPanel2a.Title = 'Modality-wise tumour behaviour';
            app.StatsPanel2b = uipanel(app.HistogramsTab);
            app.StatsPanel2b.Title = 'Modality-wise tumour behaviour (Oxygen-Enhanced)';
            app.RegisteredROIOELabel = uilabel(app.UIFigure);
            app.RegisteredROIOELabel.Text = 'Registered ROI (OE)';
            app.RegisteredRoiDropDown_OE = uidropdown(app.UIFigure);
            app.RegisteredRoiDropDown_OE.Items = {'select...'};
            app.RegisteredRoiDropDown_OE.ValueChangedFcn = createCallbackFcn(app, @RegisteredRoiDropDown_OEValueChanged, true);
            app.RegisteredRoiDropDown_OE.Value = 'select...';
            app.ExportEditField = uieditfield(app.UIFigure, 'text');
            app.ExportEditField.ValueChangedFcn = createCallbackFcn(app, @ExportEditFieldValueChanged, true);
            app.ExportEditField.Value = '/Users/ambrebertrand/Downloads/outputs.csv';
            app.Enterfilepathendingincsvtosavedataforvisits1and2Label = uilabel(app.UIFigure);
            app.Enterfilepathendingincsvtosavedataforvisits1and2Label.Text = '5. Enter filepath ending in .csv to save data for visits 1 and 2:';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.UpdateButton_2 = uibutton(app.UIFigure, 'push');
            app.UpdateButton_2.ButtonPushedFcn = createCallbackFcn(app, @UpdateButton_2Pushed, true);
            app.UpdateButton_2.Text = 'Update';
            app.ExportEditField_2 = uieditfield(app.UIFigure, 'text');
            app.ExportEditField_2.ValueChangedFcn = createCallbackFcn(app, @ExportEditField_2ValueChanged, true);
            app.ExportEditField_2.Value = '/Users/ambrebertrand/Downloads/OE_outputs.csv';
            app.ExportButton_2 = uibutton(app.UIFigure, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.Visit2EditFieldLabel_2 = uilabel(app.UIFigure);
            app.Visit2EditFieldLabel_2.Text = 'OE';
            app.Visit2EditFieldLabel_3 = uilabel(app.UIFigure);
            app.Visit2EditFieldLabel_3.Text = 'Standard';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = REACT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end